classdef CONVET < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ChatoTextArea               matlab.ui.control.TextArea
        ChatoTextAreaLabel          matlab.ui.control.Label
        BienvenidoaCONVOTunsistemadeconversindevozatextoLabel  matlab.ui.control.Label
        Image2                      matlab.ui.control.Image
        Image                       matlab.ui.control.Image
        REINICIARCONVERSACINButton  matlab.ui.control.Button
        GRABARButton                matlab.ui.control.Button
        TTextArea                   matlab.ui.control.TextArea
        TTextAreaLabel              matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        audiorecorder
        recordedData
        file = load("model.mat");
        svm
        coeff
        extra_table
        mu
        sigma
        fs = 48000;
        arduino
        led = 13;
        morse_map
    end
    
    methods (Access = private)
        function write_morse(app,message)
            message
            for i = 1:length(message)
                character = upper(message(i));
                if isKey(app.morse_map, character)
                    morse = app.morse_map(character);
                    for j=1:length(morse)
                        app.arduino.writeDigitalPin(app.led,1);
                        if morse(j) == '-'
                            pause(0.34);
                        else
                            pause(0.12);
                        end
                        app.arduino.writeDigitalPin(app.led,0);
                        pause(0.1);
                    end
                end
            end
        end


        function audio_vector = transform_audio(app,audio,fs_x)
            possible_fs = [768000, 384000, 192000, 96000, 48000, 24000, 12000, 705600, 352800, 176400, 88200, 44100, 22050, 11025, 32000, 16000, 8000];
                if(size(audio,2) > 1)
                    audio = mean(audio,2);
                end
                [~, index] = min(abs(possible_fs - fs_x));
                audio = audioresample(audio,InputRate=possible_fs(index),OutputRate=app.fs);
                mfccs = mfcc(audio,app.fs);
                mfccs = reshape(mfccs,[],1);
                centroid = spectralCentroid(audio, app.fs);
                centroid(isnan(centroid)) = 0;
                spread = spectralSpread(audio,app.fs);
                spread(isnan(spread)) = 0;
                kurtosis = spectralKurtosis(audio,app.fs);
                kurtosis(isnan(kurtosis)) = 0;
                % Concatenar las características en un solo vector
                mfcc_new = zeros([1,app.extra_table.max_mfccs]);
                mfcc_new(1:length(mfccs)) = mfccs;
                centroid_new = zeros([1,app.extra_table.max_centroid]);
                centroid_new(1:length(centroid)) = centroid;
                spread_new = zeros([1,app.extra_table.max_spread]);
                spread_new(1:length(spread)) = spread;
                kurtosis_new = zeros([1,app.extra_table.max_kurtosis]);
                kurtosis_new(1:length(kurtosis)) = kurtosis;
                features = horzcat(mfcc_new,centroid_new,spread_new,kurtosis_new);
                featuresNormalized = (features - app.mu) ./ app.sigma;
                audio_vector = featuresNormalized * app.coeff;          
            end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.svm = app.file.svm_multiclass;
            app.coeff = table2array(readtable("coeff.csv",Delimiter=','));
            app.extra_table = readtable("extra.csv",Delimiter=',');
            app.mu = table2array(readtable('mu.csv',Delimiter=','));
            app.sigma = table2array(readtable('sigma.csv',Delimiter=','));
            app.sigma(app.sigma == 0) = 1;
            app.arduino = arduino('COM3', 'Uno');
            app.morse_map = containers.Map(...
    {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', ...
    'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', ...
    '1', '2', '3', '4', '5', '6', '7', '8', '9', '0', ' '}, ...
    {'.-', '-...', '-.-.', '-..', '.', '..-.', '--.', '....', '..', '.---', '-.-', '.-..', '--', ...
    '-.', '---', '.--.', '--.-', '.-.', '...', '-', '..-', '...-', '.--', '-..-', '-.--', '--..', ...
    '.----', '..---', '...--', '....-', '.....', '-....', '--...', '---..', '----.', '-----', '/'});
        end

        % Button pushed function: GRABARButton
        function GRABARButtonPushed(app, event)
            % Verificar si está grabando actualmente
            if ~isempty(app.audiorecorder) && isrecording(app.audiorecorder)
                stop(app.audiorecorder); % Detener la grabación
                app.recordedData = getaudiodata(app.audiorecorder); % Obtener los datos grabados
                plot(app.UIAxes, app.recordedData); % Plotear los datos grabados
                title(app.UIAxes, 'Audio Grabado');
                xlabel(app.UIAxes, 'Muestras');
                ylabel(app.UIAxes, 'Amplitud');
                vector_audio = app.transform_audio(app.recordedData,app.audiorecorder.SampleRate);
                y_pred = predict(app.svm,vector_audio);
                string_pred = string(y_pred);
                cadenas = split(string_pred,';');
                app.TTextArea.Value = strcat(app.TTextArea.Value,cadenas(1));
                app.ChatoTextArea.Value = strcat(app.ChatoTextArea.Value,cadenas(2));
                app.write_morse(cadenas(2));
                app.GRABARButton.Text = 'GRABAR'; % Cambiar el texto del botón
            else
                app.audiorecorder = audiorecorder(48000,16,2); % Crear un objeto de grabación de audio
                record(app.audiorecorder); % Iniciar la grabación
                app.GRABARButton.Text = 'DETENER'; % Cambiar el texto del botón
            end
        end

        % Button pushed function: REINICIARCONVERSACINButton
        function REINICIARCONVERSACINButtonPushed(app, event)
            app.TTextArea.Value = "";
            app.ChatoTextArea.Value = "";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Voz')
            app.TTextAreaLabel = uilabel(app.UIFigure);
            app.TTextAreaLabel.Text = 'Tú:';
            app.TTextArea = uitextarea(app.UIFigure);
            app.TTextArea.Editable = 'off';
            app.GRABARButton = uibutton(app.UIFigure, 'push');
            app.GRABARButton.ButtonPushedFcn = createCallbackFcn(app, @GRABARButtonPushed, true);
            app.GRABARButton.Text = 'GRABAR';
            app.REINICIARCONVERSACINButton = uibutton(app.UIFigure, 'push');
            app.REINICIARCONVERSACINButton.ButtonPushedFcn = createCallbackFcn(app, @REINICIARCONVERSACINButtonPushed, true);
            app.REINICIARCONVERSACINButton.Text = {'REINICIAR'; 'CONVERSACIÓN'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'IPN.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'UPIIT.jpg');
            app.BienvenidoaCONVOTunsistemadeconversindevozatextoLabel = uilabel(app.UIFigure);
            app.BienvenidoaCONVOTunsistemadeconversindevozatextoLabel.Text = 'Bienvenido a CONVOT: un sistema de conversión de voz a texto.';
            app.ChatoTextAreaLabel = uilabel(app.UIFigure);
            app.ChatoTextAreaLabel.Text = 'Chato:';
            app.ChatoTextArea = uitextarea(app.UIFigure);
            app.ChatoTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CONVET

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end